classdef debugJavaScript < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        toDilate            matlab.ui.control.CheckBox
        toTranspose         matlab.ui.control.CheckBox
        UITable             matlab.ui.control.Table
        PredictButton       matlab.ui.control.Button
        signature_padLabel  matlab.ui.control.Label
        HTML                matlab.ui.control.HTML
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        imagedata % Description
    end
    
    methods (Access = private)
        
        function imshowFcn(app)
            % src: uihtml
            img = struct2array(app.HTML.Data.data);
            img = img(1:4:end);
            img = reshape(img,[28,28]);
            
            if app.toDilate.Value
                se = strel('square',2);
                img = imdilate(img,se);
            end
            
            if app.toTranspose.Value
                img = img';
            end
            app.imagedata = img;
            
            contour(app.UIAxes,app.imagedata);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.HTML.HTMLSource = fullfile(pwd,'index.html');
            app.HTML.DataChangedFcn = @(src,event) imshowFcn(app);
            
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.LineWidth = 2;
            
            app.toTranspose.ValueChangedFcn = @(src,event) imshowFcn(app);
            app.toDilate.ValueChangedFcn = @(src,event) imshowFcn(app);
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            removeStyle(app.UITable)
            
            tmp = app.imagedata;
             scores = digitPredictFcn_CNN(tmp(:))';
              scores = digitPredictFcn(tmp(:));
            app.UITable.Data = scores;
            app.UITable.RowName = ["0","1","2","3","4","5","6","7","8","9"];
            
            [~,idx] = max(scores);
            s = uistyle('BackgroundColor','yellow');
            addStyle(app.UITable,s,'row',idx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'on MATLAB')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.HTML = uihtml(app.UIFigure);
            app.signature_padLabel = uilabel(app.UIFigure);
            app.signature_padLabel.Text = 'signature_pad';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'score'};
            app.UITable.RowName = {};
            app.toTranspose = uicheckbox(app.UIFigure);
            app.toTranspose.Text = '転置';
            app.toDilate = uicheckbox(app.UIFigure);
            app.toDilate.Text = '膨張';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = debugJavaScript

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end